classdef proyecto_metodos_numericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TrazadorCbicoNaturalTab       matlab.ui.container.Tab
        TrazadorCbicoNaturalPanel     matlab.ui.container.Panel
        CalcularButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CantidaddePuntosSpinner       matlab.ui.control.Spinner
        CantidaddePuntosSpinnerLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        MtodoIterativoJacobiTab       matlab.ui.container.Tab
        MtodoIterativoJacobiPanel     matlab.ui.container.Panel
        MtododelaPotenciaTab          matlab.ui.container.Tab
        MtododelaPotenciaPanel        matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CantidaddePuntosSpinner, 
        % TrazadorCbicoNaturalPanel
        function TrazadorCbicoNaturalPanelSizeChanged(app, event)
            numberOfEntries = event.Value;  
            currentTableData = app.UITable.Data;            
            if(size(currentTableData) > 0)
                if(numberOfEntries > event.PreviousValue)
                    newRow = [0 0];
                    newTableData = [currentTableData; newRow];
                    app.UITable.Data = newTableData;
                else                    
                    currentTableData(end,:) = [];
                    app.UITable.Data = currentTableData;
                end
            else
                app.UITable.Data = zeros(numberOfEntries, 2);
            end    
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;             
            index = indices(1,1);
            
            if(index > 1)
                previusRowIndex = index-1;
                previusRowData = app.UITable.Data(previusRowIndex, 1);
                if(previusRowData >= newData)
                    uialert(app.UIFigure,'El valor ingresado debe ser mayor al anterior del eje x!','Valor incorrecto',...
                    'Icon','error');
                    app.UITable.Data(index, 1) = event.PreviousData;
                end                
            end            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrazadorCbicoNaturalTab = uitab(app.TabGroup);
            app.TrazadorCbicoNaturalTab.Title = 'Trazador Cúbico Natural';
            app.TrazadorCbicoNaturalPanel = uipanel(app.TrazadorCbicoNaturalTab);
            app.TrazadorCbicoNaturalPanel.Title = 'Trazador Cúbico Natural';
            app.TrazadorCbicoNaturalPanel.SizeChangedFcn = createCallbackFcn(app, @TrazadorCbicoNaturalPanelSizeChanged, true);
            app.UIAxes = uiaxes(app.TrazadorCbicoNaturalPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CantidaddePuntosSpinnerLabel = uilabel(app.TrazadorCbicoNaturalPanel);
            app.CantidaddePuntosSpinnerLabel.Text = 'Cantidad de Puntos:';
            app.CantidaddePuntosSpinner = uispinner(app.TrazadorCbicoNaturalPanel);
            app.CantidaddePuntosSpinner.Limits = [1 Inf];
            app.CantidaddePuntosSpinner.ValueChangedFcn = createCallbackFcn(app, @TrazadorCbicoNaturalPanelSizeChanged, true);
            app.CantidaddePuntosSpinner.Value = 1;
            app.UITable = uitable(app.TrazadorCbicoNaturalPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CalcularButton = uibutton(app.TrazadorCbicoNaturalPanel, 'push');
            app.CalcularButton.Text = 'Calcular';
            app.MtodoIterativoJacobiTab = uitab(app.TabGroup);
            app.MtodoIterativoJacobiTab.Title = 'Método Iterativo Jacobi';
            app.MtodoIterativoJacobiPanel = uipanel(app.MtodoIterativoJacobiTab);
            app.MtodoIterativoJacobiPanel.Title = 'Método Iterativo Jacobi';
            app.MtododelaPotenciaTab = uitab(app.TabGroup);
            app.MtododelaPotenciaTab.Title = 'Método de la Potencia';
            app.MtododelaPotenciaPanel = uipanel(app.MtododelaPotenciaTab);
            app.MtododelaPotenciaPanel.Title = 'Método de la Potencia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_metodos_numericos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end